classdef Raman_Plot_Viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ButtonGroup       matlab.ui.container.ButtonGroup
        LinesButton       matlab.ui.control.ToggleButton
        NoLinesButton     matlab.ui.control.ToggleButton
        SaveFigureButton  matlab.ui.control.Button
        ChooseDataButton  matlab.ui.control.Button
        Slider            matlab.ui.control.Slider
        OneDownButton     matlab.ui.control.Button
        OneUpButton       matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: ChooseDataButton
        function ChooseDataButtonPushed(app, event)
            %% Data Selection Prompt
            [file, path] = uigetfile({'*.txt'});
            data_name = fullfile(path, file);
            data = table2array(readtable(data_name));
            
            %% Logic for Scans of Different Dimensions
            % Determine data array size
            [~,x,~] = unique(data(:,1),'rows');
            [~,y,~] = unique(data(:,2),'rows');
            [~,s,~] = unique(data(:,3),'rows');
            
            [x_size, ~] = size(x);
            [y_size, ~] = size(y);
            [s_size, ~] = size(s);
            
            %% Convert raw data to plottable format
            sorted_data = cell(x_size,y_size,2);
            sorted_data(:,:,1) = mat2cell(data(s_size:-1:1,3),s_size,1); 
            
            for j = 1:y_size
                for i = 1:x_size
                    l = (i-1)*s_size + (j-1)*x_size*s_size + 1;
                    b = i*s_size + (j-1)*x_size*s_size;
        
                    sorted_data(i,j,2) = mat2cell(normalize(data(b:-1:l,4),1,'range'),s_size,1);
                end
            end
            
            %% Graph Labels
            title_text = '\textbf{Raman Viewer}';
            x_text = '\textbf{Raman Shift (cm$^{-1}$)}';
            y_text = '\textbf{Intensity}';
            
            %% Extract I and J from index
            ij = x_size * y_size;
            index = 1;
            i = mod(index, x_size);
            if i == 0
                i = x_size;
            end
            j = ceil(index/x_size);
            
            %% Start Plot
            Wavenumber = sorted_data{1,1,1};
            Intensity = sorted_data{1,1,2};
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            
        end

        % Button pushed function: OneDownButton
        function OneDownButtonPushed(app, event)
            
        end

        % Button pushed function: OneUpButton
        function OneUpButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OneUpButton = uibutton(app.UIFigure, 'push');
            app.OneUpButton.ButtonPushedFcn = createCallbackFcn(app, @OneUpButtonPushed, true);
            app.OneUpButton.Text = 'One Up';
            app.OneDownButton = uibutton(app.UIFigure, 'push');
            app.OneDownButton.ButtonPushedFcn = createCallbackFcn(app, @OneDownButtonPushed, true);
            app.OneDownButton.Text = 'One Down';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.ChooseDataButton = uibutton(app.UIFigure, 'push');
            app.ChooseDataButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDataButtonPushed, true);
            app.ChooseDataButton.Text = 'Choose Data';
            app.SaveFigureButton = uibutton(app.UIFigure, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.NoLinesButton = uitogglebutton(app.ButtonGroup);
            app.NoLinesButton.Text = 'No Lines';
            app.NoLinesButton.Value = true;
            app.LinesButton = uitogglebutton(app.ButtonGroup);
            app.LinesButton.Text = 'Lines';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Raman_Plot_Viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end